classdef dpd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NationalInstrumentsMATLABDPDDemoUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DUTWaveformTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        DUTTab                          matlab.ui.container.Tab
        CenterFrequencyHzLabel          matlab.ui.control.Label
        CenterFrequencyEditField        matlab.ui.control.NumericEditField
        TargetInputPowerdBmLabel        matlab.ui.control.Label
        TargetInputPowerEditField       matlab.ui.control.NumericEditField
        ExpectedGaindBEditFieldLabel    matlab.ui.control.Label
        ExpectedGainEditField           matlab.ui.control.NumericEditField
        GainAccuracydBEditFieldLabel    matlab.ui.control.Label
        GainAccuracyEditField           matlab.ui.control.NumericEditField
        WaveformTab_2                   matlab.ui.container.Tab
        PathLabel                       matlab.ui.control.Label
        WaveformPathEditField           matlab.ui.control.EditField
        BurstedCheckBox                 matlab.ui.control.CheckBox
        SignalTypeDropDownLabel         matlab.ui.control.Label
        WaveformSignalTypeDropDown      matlab.ui.control.DropDown
        BrowseButton                    matlab.ui.control.Button
        GNRTab                          matlab.ui.container.Tab
        BandwidthMHzDropDownLabel       matlab.ui.control.Label
        BandwidthDropDown               matlab.ui.control.DropDown
        TransformPrecodingCheckBox      matlab.ui.control.CheckBox
        ModulationTypeDropDownLabel     matlab.ui.control.Label
        ModulationTypeDropDown          matlab.ui.control.DropDown
        SubcarrierSpacingkHzDropDownLabel  matlab.ui.control.Label
        SubcarrierSpacingDropDown       matlab.ui.control.DropDown
        MeasurementsTab                 matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        DPDTab                          matlab.ui.container.Tab
        DpdEnabledCheckBox              matlab.ui.control.CheckBox
        IterationsSpinnerLabel          matlab.ui.control.Label
        DpdIterationsSpinner            matlab.ui.control.Spinner
        MeasurementIntervalsEditFieldLabel_2  matlab.ui.control.Label
        DpdMeasurementIntervalEditField  matlab.ui.control.NumericEditField
        NanoSemiLinearizerPanel         matlab.ui.container.Panel
        LevelDropDownLabel              matlab.ui.control.Label
        DpdLevelDropDown                matlab.ui.control.DropDown
        TrainingSamplesEditFieldLabel   matlab.ui.control.Label
        DpdTrainingSamplesEditField     matlab.ui.control.NumericEditField
        RhoEditFieldLabel               matlab.ui.control.Label
        DpdRhoEditField                 matlab.ui.control.NumericEditField
        ToolchainDropDownLabel          matlab.ui.control.Label
        DpdToolchainDropDown            matlab.ui.control.DropDown
        MemoryPolynomialPanel           matlab.ui.container.Panel
        OrderSpinnerLabel               matlab.ui.control.Label
        DpdOrderSpinner                 matlab.ui.control.Spinner
        MemorySpinnerLabel              matlab.ui.control.Label
        DpdMemorySpinner                matlab.ui.control.Spinner
        AMPMTab                         matlab.ui.container.Tab
        MeasurementIntervalsEditFieldLabel  matlab.ui.control.Label
        AmpmMeasurementIntervalEditField  matlab.ui.control.NumericEditField
        ThresholdPanel                  matlab.ui.container.Panel
        StateDropDownLabel_2            matlab.ui.control.Label
        AmpmThresholdState              matlab.ui.control.DropDown
        LeveldBPeakEditFieldLabel       matlab.ui.control.Label
        AmpmThresholdLevel              matlab.ui.control.NumericEditField
        ACPTab                          matlab.ui.container.Tab
        AveragingPanel                  matlab.ui.container.Panel
        NumberLabel                     matlab.ui.control.Label
        AcpAveragingNumberEditField     matlab.ui.control.NumericEditField
        AcpAveragingEnabledCheckBox     matlab.ui.control.CheckBox
        TypeDropDownLabel               matlab.ui.control.Label
        AcpAveragingTypeDropDown        matlab.ui.control.DropDown
        RBWFilterPanel                  matlab.ui.container.Panel
        AcpRbwAutoCheckBox              matlab.ui.control.CheckBox
        BandwidthHzEditFieldLabel       matlab.ui.control.Label
        AcpRbwBandwidthEditField        matlab.ui.control.NumericEditField
        FilterTypeLabel                 matlab.ui.control.Label
        AcpRbwFilterTypeDropDown        matlab.ui.control.DropDown
        NROffsetsSpinnerLabel           matlab.ui.control.Label
        AcpNROffsetsSpinner             matlab.ui.control.Spinner
        HardwareTab                     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        VSATab                          matlab.ui.container.Tab
        TriggerPanel                    matlab.ui.container.Panel
        SourceDropDownLabel             matlab.ui.control.Label
        VsaTriggerSourceDropDown        matlab.ui.control.DropDown
        EdgeDropDownLabel               matlab.ui.control.Label
        VsaTriggerEdgeDropDown          matlab.ui.control.DropDown
        DelaysSpinnerLabel              matlab.ui.control.Label
        VsaTriggerDelaySpinner          matlab.ui.control.Spinner
        TimeoutsSpinnerLabel            matlab.ui.control.Label
        VsaTriggerTimeoutSpinner        matlab.ui.control.Spinner
        StateDropDownLabel              matlab.ui.control.Label
        VsaTriggerStateDropDown         matlab.ui.control.DropDown
        LOOffsetPanel                   matlab.ui.container.Panel
        ModeDropDownLabel               matlab.ui.control.Label
        VsaLoOffsetModeDropDown         matlab.ui.control.DropDown
        OffsetHzEditFieldLabel          matlab.ui.control.Label
        VsaLoOffsetFrequencyEditField   matlab.ui.control.NumericEditField
        ResourceNameLabel_2             matlab.ui.control.Label
        VsaResourceNameDropDown         matlab.ui.control.DropDown
        ExternalAttenuationdBEditFieldLabel_2  matlab.ui.control.Label
        VsaExternalAttenuationEditField  matlab.ui.control.NumericEditField
        FrequencyReferenceDropDown_2Label  matlab.ui.control.Label
        VsaFrequencyReferenceDropDown   matlab.ui.control.DropDown
        MeasurementTimeoutsEditFieldLabel  matlab.ui.control.Label
        VsaMeasurementTimeoutEditField  matlab.ui.control.NumericEditField
        VSGTab                          matlab.ui.container.Tab
        MarkerPanel                     matlab.ui.container.Panel
        DestinationDropDownLabel        matlab.ui.control.Label
        VsgMarkerDestinationDropDown    matlab.ui.control.DropDown
        LOOffsetPanel_2                 matlab.ui.container.Panel
        ModeDropDownLabel_2             matlab.ui.control.Label
        VsgLoOffsetModeDropDown         matlab.ui.control.DropDown
        OffsetHzEditFieldLabel_2        matlab.ui.control.Label
        VsgLoOffsetFrequencyEditField   matlab.ui.control.NumericEditField
        ResourceNameLabel               matlab.ui.control.Label
        VsgResourceNameDropDown         matlab.ui.control.DropDown
        ExternalAttenuationdBEditFieldLabel  matlab.ui.control.Label
        VsgExternalAttenuationEditField  matlab.ui.control.NumericEditField
        FrequencyReferenceDropDownLabel  matlab.ui.control.Label
        VsgFrequencyReferenceDropDown   matlab.ui.control.DropDown
        RunButton                       matlab.ui.control.StateButton
        Graph0                          matlab.ui.control.UIAxes
        Graph1                          matlab.ui.control.UIAxes
        Graph0DropDown                  matlab.ui.control.DropDown
        Graph1DropDown                  matlab.ui.control.DropDown
        Graph2                          matlab.ui.control.UIAxes
        Graph2DropDown                  matlab.ui.control.DropDown
        UITable                         matlab.ui.control.Table
        StatusTextLabel                 matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        vsg % vector signal generator session
        vsa % rfmx instr session
        specAn % rfmx specan session
        nr % rfmx nr session
        vsgHandle % Description
    end
    
    methods (Access = private)
        
        function scaledComplexArray = ScaleComplexArray(~, complexArray, desiredAveragePower)
            avgPower = 20*log10(rms(complexArray)) + 10;
            scaleFactor = 10^((desiredAveragePower - avgPower)/20);
            scaledComplexArray = complexArray * scaleFactor;
        end
        
        function [freq, spectrum] = FetchACP(app, selectorString, timeout)
            [~, spectrumNet] = app.nr.Acp.Results.FetchSpectrum(selectorString, timeout, []);
            spectrum = single(spectrumNet.GetData());
            freq = (0:single(spectrumNet.SampleCount) - 1) * spectrumNet.FrequencyIncrement + spectrumNet.StartFrequency;
        end
        
        function [i, q] = FetchConstellation(app, selectorString, timeout)
            import NationalInstruments.*;
            complexSingleArrayType = ComplexSingle.ComposeArray(0, 0);
            [~, constallation] = app.nr.ModAcc.Results.FetchPuschDataConstellationTrace(selectorString, timeout, complexSingleArrayType);
            [i, q] = ComplexSingle.DecomposeArray(constallation);
            i = single(i);
            q = single(q);
        end

        function [subcarrier, evm] = FetchEvmVsSubcarrier(app, selectorString, timeout)
            [~, evmNet] = app.nr.ModAcc.Results.FetchRmsEvmPerSubcarrierMeanTrace(selectorString, timeout, []);
            evm = evmNet.GetRawData();
            evm = single(evm);
            subcarrier = (0:single(evmNet.SampleCount) - 1) * evmNet.Timing.SampleInterval.TotalSeconds;
        end
        
        function [symbol, evm] = FetchEvmVsSymbol(app, selectorString, timeout)
            [~, evmNet] = app.nr.ModAcc.Results.FetchRmsEvmPerSymbolMeanTrace(selectorString, timeout, []);
            evm = evmNet.GetRawData();
            evm = single(evm);
            symbol = (0:single(evmNet.SampleCount) - 1) * evmNet.Timing.SampleInterval.TotalSeconds;
        end
        
        function UpdateDisplay(app, resultName, vsaMeasurementTimeout)
            selectorString = strcat('result::', resultName);
            if strcmp(resultName, 'preDpd')
                resultIndex = 0;
                color = 'b';
            else
                color = 'r';
                resultIndex = 1;
            end
            dot = strcat(color, '.');
            line = strcat(color, '-');
            try
                % Fetch and Plot ACP %
                [df, spectrum] = app.FetchACP(selectorString, vsaMeasurementTimeout);
                plot(app.Graph0, df, spectrum, line);
                axis(app.Graph0, "manual");
                
                % Fetch and Plot Graph1 %
                switch app.Graph1DropDown.Value
                    case 'Constellation'
                        [i, q] = app.FetchConstellation(selectorString, vsaMeasurementTimeout);
                        plot(app.Graph1, i, q, dot);
                    case 'EVM vs. Subcarrier'
                        [subcarrier, evm] = app.FetchEvmVsSubcarrier(selectorString, vsaMeasurementTimeout);
                        plot(app.Graph1, subcarrier, evm, line);
                    case 'EVM vs. Symbol'
                        [symbol, evm] = app.FetchEvmVsSymbol(selectorString, vsaMeasurementTimeout);
                        plot(app.Graph1, symbol, evm, line);
                end
                axis(app.Graph1, "manual");
                                
                % Fetch and Plot Graph2 %
                switch app.Graph2DropDown.Value
                    case 'AM-Gain'
                        [~, referencePowersAMToAM, measuredAMToAM, ~] = app.specAn.Ampm.Results.FetchAMToAMTrace(selectorString, vsaMeasurementTimeout, [], [], []);
                        plot(app.Graph2, referencePowersAMToAM, measuredAMToAM, dot);
                    case 'AM-AM'
                        [~, referencePowersAMToAM, measuredAMToAM, ~] = app.specAn.Ampm.Results.FetchAMToAMTrace(selectorString, vsaMeasurementTimeout, [], [], []);
                        referencePowersAMToAM = single(referencePowersAMToAM);
                        measuredAMToAM = single(measuredAMToAM);
                        plot(app.Graph2, referencePowersAMToAM, referencePowersAMToAM + measuredAMToAM, dot);
                    case 'AM-PM'
                        [~, referencePowersAMToPM, measuredAMToPM, ~] = app.specAn.Ampm.Results.FetchAMToPMTrace(selectorString, vsaMeasurementTimeout, [], [], []);
                        plot(app.Graph2, referencePowersAMToPM, measuredAMToPM, dot);
                end
                axis(app.Graph2, "manual");
                
                % Fetch Scalar Results %
                [~, totalPower] = app.nr.Acp.Results.FetchTotalAggregatedPower(selectorString, vsaMeasurementTimeout);
                app.UITable.Data(1, 1) = {'ACP NR Channel Power (dBm)'};
                app.UITable.Data(1, resultIndex + 2) = {totalPower};
                
                app.UITable.Data(2, 1) = {'ACP NR Offset 1 Lower (dB)'};
                app.UITable.Data(3, 1) = {'ACP NR Offset 1 Upper (dB)'};
                offsetString = strcat(selectorString, '/offset2'); %RFmxNRMX.BuildOffsetString(selectorString, 2);
                [~, lowerRelativePower, upperRelativePower, ~, ~] = app.nr.Acp.Results.FetchOffsetMeasurement(offsetString, vsaMeasurementTimeout);
                app.UITable.Data(2, resultIndex + 2) = {lowerRelativePower};
                app.UITable.Data(3, resultIndex + 2) = {upperRelativePower};
                app.UITable.Data(4, 1) = {'ACP NR Offset 2 Lower (dB)'};
                app.UITable.Data(5, 1) = {'ACP NR Offset 3 Upper (dB)'};
                offsetString = strcat(selectorString, '/offset3'); %RFmxNRMX.BuildOffsetString(selectorString, 3);
                [~, lowerRelativePower, upperRelativePower, ~, ~] = app.nr.Acp.Results.FetchOffsetMeasurement(offsetString, vsaMeasurementTimeout);
                app.UITable.Data(4, resultIndex + 2) = {lowerRelativePower};
                app.UITable.Data(5, resultIndex + 2) = {upperRelativePower};
                
                app.UITable.Data(6, 1) = {'EVM (%)'};
                [~, evm] = app.nr.ModAcc.Results.GetCompositeRmsEvmMean(selectorString);
                app.UITable.Data(6, resultIndex + 2) = {evm};
                
            catch ex % abort measurements if there is an error
                app.vsg.Abort();
                app.nr.AbortMeasurements('');
                rethrow(ex)
            end
        end

        function complexArray = GetComplexArray(~, netWaveform)
            import NationalInstruments.*;
            netComplexArray = netWaveform.GetRawData();
            [i, q] = ComplexSingle.DecomposeArray(netComplexArray);
            i = single(i);
            q = single(q);
            complexArray = i + 1j * q;
        end
        
        function netWaveform = GetNetWaveform(~, complexArray)
            import NationalInstruments.*;
            i = real(complexArray);
            q = imag(complexArray);
            netComplexArray = ComplexSingle.ComposeArray(i, q);
            netWaveform = NET.createGeneric('NationalInstruments.ComplexWaveform', {'NationalInstruments.ComplexSingle'}, 0, netComplexArray.Length);
            netWaveform.Append(netComplexArray);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            NET.addAssembly('Ivi.Driver');
            NET.addAssembly('NationalInstruments.Common');
            NET.addAssembly('NationalInstruments.ModularInstruments.NIRfsg.Fx40');
            NET.addAssembly('NationalInstruments.ModularInstruments.NIRfsgPlayback.Fx40');
            NET.addAssembly('NationalInstruments.RFmx.InstrMX.Fx40');
            NET.addAssembly('NationalInstruments.RFmx.SpecAnMX.Fx40');
            NET.addAssembly('NationalInstruments.RFmx.NRMX.Fx40');
            NET.addAssembly('NationalInstruments.ModularInstruments.ModularInstrumentsSystem');
            
            import NationalInstruments.ModularInstruments.SystemServices.DeviceServices.*;
            
            rfsaDevices = ModularInstrumentsSystem('NI-RFSA').DeviceCollection;
            for i = 0:rfsaDevices.Count-1
                deviceInfo = Item(rfsaDevices, i);
                deviceName = char(deviceInfo.Name);
                app.VsaResourceNameDropDown.Items = [app.VsaResourceNameDropDown.Items deviceName];
            end
            
            rfsgDevices = ModularInstrumentsSystem('NI-RFSG').DeviceCollection;
            for i = 0:rfsgDevices.Count-1
                deviceInfo = Item(rfsgDevices, i);
                deviceName = char(deviceInfo.Name);
                app.VsgResourceNameDropDown.Items = [app.VsgResourceNameDropDown.Items deviceName];
            end
        end

        % Value changed function: VsgResourceNameDropDown
        function VsgResourceChanged(app, event)
            value = app.VsgResourceNameDropDown.Value;
            
            import NationalInstruments.ModularInstruments.NIRfsg.*;

            try
                app.vsg.Close();
            catch
                % do nothing if errors out on close
            end
            
            try
                app.StatusTextLabel.Text = 'Initializing generator..';
                drawnow limitrate
                app.vsg = NIRfsg(value, false, true);
                app.vsgHandle = app.vsg.DangerousGetInstrumentHandle();
            catch
                
            end
            
            app.StatusTextLabel.Text = 'Waiting on user..';
            drawnow limitrate
        end

        % Value changed function: VsaResourceNameDropDown
        function VsaResourceChanged(app, event)
            value = app.VsaResourceNameDropDown.Value;
            
            import NationalInstruments.RFmx.InstrMX.*;
            import NationalInstruments.RFmx.SpecAnMX.*;
            import NationalInstruments.RFmx.NRMX.*;
            
            try
                app.vsa.Close();
            catch
                % do nothing if errors out on close
            end
            
            try
                app.StatusTextLabel.Text = 'Initializing analyzer..';
                drawnow limitrate
                app.vsa = RFmxInstrMX(value, '');
                app.specAn = RFmxSpecAnMXExtension.GetSpecAnSignalConfiguration(app.vsa);
                app.nr = RFmxNRMXExtension.GetNRSignalConfiguration(app.vsa);
            catch ex
                disp(ex.message)
            end
            
            app.StatusTextLabel.Text = 'Waiting on user..';
            drawnow limitrate
        end

        % Close request function: 
        % NationalInstrumentsMATLABDPDDemoUIFigure
        function NationalInstrumentsMATLABDPDDemoUIFigureCloseRequest(app, event)
            
            try
                app.vsa.ForceClose();
            catch
                % do nothing if errors out on close
            end
            
            try
                app.vsg.Close();
            catch
                % do nothing if errors out on close
            end
            
            delete(app)
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
          
            run = app.RunButton.Value;

            if run
                import NationalInstruments.ModularInstruments.NIRfsg.*;
                import NationalInstruments.ModularInstruments.NIRfsgPlayback.*;
                import NationalInstruments.RFmx.InstrMX.*;
                import NationalInstruments.RFmx.SpecAnMX.*;
                import NationalInstruments.RFmx.NRMX.*;
                
                app.StatusTextLabel.Text = 'Fetching configuration..';
                drawnow limitrate % draw and interupt if needed
                
                % Get DUT Values %
                dutExpectedGain = app.ExpectedGainEditField.Value;
                dutGainAccuracy = app.GainAccuracyEditField.Value;
                dutTargetInputPower = app.TargetInputPowerEditField.Value;
                
                centerFrequency = app.CenterFrequencyEditField.Value;
                
                % Get Reference Waveform Data %
                referenceWaveformPath = app.WaveformPathEditField.Value;
                [~, referenceNetWaveform] = NIRfsgPlayback.ReadWaveformFromFileComplex(referenceWaveformPath, []);
                referenceWaveform = GetComplexArray(app, referenceNetWaveform);
                referenceWaveformAveragePower = 20*log10(rms(referenceWaveform)) + 10;
                
                [~, referenceWaveformPapr] = NIRfsgPlayback.ReadPaprFromFile(referenceWaveformPath, 0);
                [~, referenceWaveformSampleRate] = NIRfsgPlayback.ReadSampleRateFromFile(referenceWaveformPath, 0);
                
                referenceWaveformIdleDurationPresent = app.BurstedCheckBox.Value;
                referenceWaveformSignalType = app.WaveformSignalTypeDropDown.Value;
                referenceWaveformBandwidth = str2double(app.BandwidthDropDown.Value) * 1e6;
                referenceWaveformSubcarrierSpacing = str2double(app.SubcarrierSpacingDropDown.Value) * 1e3;
                if app.TransformPrecodingCheckBox.Value
                    referenceWaveformTransformPrecodingEnabled = RFmxNRMXPuschTransformPrecodingEnabled.True;
                else
                    referenceWaveformTransformPrecodingEnabled = RFmxNRMXPuschTransformPrecodingEnabled.False;
                end
                switch app.ModulationTypeDropDown.Value
                    case 'PI/2 BPSK'
                        referenceWaveformModulationType = RFmxNRMXPuschModulationType.PiBy2_Bpsk;
                    case 'QPSK'
                        referenceWaveformModulationType = RFmxNRMXPuschModulationType.Qpsk;
                    case 'QAM16'
                        referenceWaveformModulationType = RFmxNRMXPuschModulationType.Qam16;
                    case 'QAM64'
                        referenceWaveformModulationType = RFmxNRMXPuschModulationType.Qam64;
                    case 'QAM256'
                        referenceWaveformModulationType = RFmxNRMXPuschModulationType.Qam256;
                    otherwise
                        error('Unknown modulation type.');
                end
                
                referenceWaveformName = 'reference';
                referenceWaveformScript = 'script refScript repeat forever generate reference marker1(0) end repeat end script';
                
                % Get VSA Configuration %
                vsaExternalAttenuation = app.VsaExternalAttenuationEditField.Value;
                vsaFrequenceReferenceSource = app.VsaFrequencyReferenceDropDown.Value;
                vsaTriggerState = strcmp(app.VsaTriggerStateDropDown.Value, 'Enabled');
                vsaTriggerSource = app.VsaTriggerSourceDropDown.Value;
                vsaTriggerEdge = app.VsaTriggerEdgeDropDown.Value;
                vsaTriggerDelay = app.VsaTriggerDelaySpinner.Value;
                vsaTriggerTimeout = app.VsaTriggerTimeoutSpinner.Value;
                vsaMeasurementTimeout = app.VsaMeasurementTimeoutEditField.Value;
                vsaLoOffsetMode = app.VsaLoOffsetModeDropDown.Value;
                vsaLoOffsetFrequency = app.VsaLoOffsetFrequencyEditField.Value;

                % Get SpecAn Common Measurement Configuration %
                if strcmp(vsaTriggerEdge, 'Falling')
                    specAnTriggerEdge = RFmxSpecAnMXDigitalEdgeTriggerEdge.Falling;
                else
                    specAnTriggerEdge = RFmxSpecAnMXDigitalEdgeTriggerEdge.Rising;
                end
                
                % Get DPD General Configuration %
                dpdEnabled = app.DpdEnabledCheckBox.Value;
                dpdToolchain = app.DpdToolchainDropDown.Value;
                dpdInterations = app.DpdIterationsSpinner.Value;
                dpdMeasurementInterval = app.DpdMeasurementIntervalEditField.Value;
                dpdOrder = app.DpdOrderSpinner.Value;
                dpdMlMemory = app.DpdMemorySpinner.Value;
                dpdRFmxMemory = dpdMlMemory - 1;  % RFmx memory doesn't include first term but matlab does
                dpdSampleRateMode = RFmxSpecAnMXDpdMeasurementSampleRateMode.ReferenceWaveform; % #const
                
                % Get RFmxSpecAn DPD Configuration %
                switch referenceWaveformSignalType
                    case 'Tones'
                        dpdSignalType = RFmxSpecAnMXDpdSignalType.Tones;
                    case 'Modulated'
                        dpdSignalType = RFmxSpecAnMXDpdSignalType.Modulated;
                end
                dpdModel = RFmxSpecAnMXDpdModel.MemoryPolynomial; % #const
                if referenceWaveformIdleDurationPresent
                    dpdReferenceWaveformIdleDurationPresent = RFmxSpecAnMXDpdReferenceWaveformIdleDurationPresent.True;
                    dpdApplyDpdIdleDurationPresent = RFmxSpecAnMXDpdApplyDpdIdleDurationPresent.True;
                else
                    dpdReferenceWaveformIdleDurationPresent = RFmxSpecAnMXDpdReferenceWaveformIdleDurationPresent.False;
                    dpdApplyDpdIdleDurationPresent = RFmxSpecAnMXDpdApplyDpdIdleDurationPresent.False;
                end

                % Get SpecAn IQ / nstdpd Configuration %
                switch app.DpdLevelDropDown.Value
                    case 'Level 0'
                        dpdLevel = 'NST_DPD_LEVEL0';
                    case 'Level 1'
                        dpdLevel = 'NST_DPD_LEVEL1';
                    case 'Level 2'
                        dpdLevel = 'NST_DPD_LEVEL2';
                    case 'Level 3'
                        dpdLevel = 'NST_DPD_LEVEL3';
                end
                dpdRho = app.DpdRhoEditField.Value;
                dpdTrainingSamples = app.DpdTrainingSamplesEditField.Value;
                
                % Get SpecAn AMPM Measurement Configuration %
                ampmMeasurementInterval = app.AmpmMeasurementIntervalEditField.Value;
                if app.AmpmThresholdState.Value
                    ampmThresholdEnabled = RFmxSpecAnMXAmpmThresholdEnabled.True;
                else
                    ampmThresholdEnabled = RFmxSpecAnMXAmpmThresholdEnabled.False;
                end
                ampmThresholdLevel = app.AmpmThresholdLevel.Value;
                ampmThresholdType = RFmxSpecAnMXAmpmThresholdType.Relative;
                ampmReferencePowerType = RFmxSpecAnMXAmpmReferencePowerType.Input;
                if referenceWaveformIdleDurationPresent
                    ampmreferenceWaveformIdleDurationPresent = RFmxSpecAnMXAmpmReferenceWaveformIdleDurationPresent.True;
                else
                    ampmreferenceWaveformIdleDurationPresent = RFmxSpecAnMXAmpmReferenceWaveformIdleDurationPresent.False;
                end
                switch referenceWaveformSignalType
                    case 'Tones'
                        ampmWaveformSignalType = RFmxSpecAnMXAmpmSignalType.Tones;
                    case 'Modulated'
                        ampmWaveformSignalType = RFmxSpecAnMXAmpmSignalType.Modulated;
                end

                % Get NR Common Measurement Configuration %
                if strcmp(vsaTriggerEdge, 'Falling')
                    nrTriggerEdge = RFmxNRMXDigitalEdgeTriggerEdge.Falling;
                else
                    nrTriggerEdge = RFmxNRMXDigitalEdgeTriggerEdge.Rising;
                end
                
                % Get NR ACP Measurement Configuration %
                acpNROffsets = app.AcpNROffsetsSpinner.Value;
                if app.AcpAveragingEnabledCheckBox.Value
                    acpAveragingEnabled = RFmxNRMXAcpAveragingEnabled.True;
                else
                    acpAveragingEnabled = RFmxNRMXAcpAveragingEnabled.False;
                end
                acpAveragingNumber = app.AcpAveragingNumberEditField.Value;
                switch app.AcpAveragingTypeDropDown.Value
                    case 'RMS'
                        acpAveragingType = RFmxNRMXAcpAveragingType.Rms;
                    otherwise
                        error('Not implemented');
                end
                switch app.AcpRbwFilterTypeDropDown.Value
                    case 'Gaussian'
                        acpRbwFilterType = RFmxNRMXAcpRbwFilterType.Gaussian;
                    otherwise
                        error('Not implemented');
                end    
                if app.AcpRbwAutoCheckBox.Value
                    acpRbwAuto = RFmxNRMXAcpRbwAutoBandwidth.True;
                else
                    acpRbwAuto = RFmxNRMXAcpRbwAutoBandwidth.False;
                end
                acpRbwBandwidth = app.AcpRbwBandwidthEditField.Value;
                
                % Get VSG Configuration %
                vsgExternalAttenuation = app.VsgExternalAttenuationEditField.Value;
                vsgFrequencyReferenceSource = app.VsgFrequencyReferenceDropDown.Value;
                switch vsgFrequencyReferenceSource
                    case 'OnboardClock'
                        vsgFrequencyReferenceSource = RfsgFrequencyReferenceSource.OnboardClock;
                    case 'RefIn'
                        vsgFrequencyReferenceSource = RfsgFrequencyReferenceSource.ReferenceIn;
                    case 'PXI_CLK'
                        vsgFrequencyReferenceSource = RfsgFrequencyReferenceSource.PxiClock;
                    otherwise
                        error('Unknown VSG reference clock source.');
                end
                vsgMarkerDestination = app.VsgMarkerDestinationDropDown.Value;
                switch vsgMarkerDestination
                    case 'PXI_Trig0'
                        vsgMarkerDestination = RfsgMarkerEventExportedOutputTerminal.PxiTriggerLine0;
                    case 'PXI_Trig3'
                        vsgMarkerDestination = RfsgMarkerEventExportedOutputTerminal.PxiTriggerLine3;
                    case 'PXI_Trig6'
                        vsgMarkerDestination = RfsgMarkerEventExportedOutputTerminal.PxiTriggerLine6;
                    otherwise
                        error('Not implemented');
                end
                switch app.VsgLoOffsetModeDropDown.Value
                    case 'No Offset'
                        vsgLoOffsetMode = NIRfsgPlaybackLOOffsetMode.NoOffset;
                    case 'Automatic'
                        vsgLoOffsetMode = NIRfsgPlaybackLOOffsetMode.Auto;
                    case 'User Defined'
                        vsgLoOffsetMode = NIRfsgPlaybackLOOffsetMode.Disabled;
                end
                vsgLoOffsetFrequency = app.VsgLoOffsetFrequencyEditField.Value;
                
                app.StatusTextLabel.Text = 'Configuring instrument..';
                drawnow limitrate % draw and interupt if needed
                
                % Configure Instr %
                app.vsa.ConfigureFrequencyReference('', vsaFrequenceReferenceSource, 10e6);
                vsaReferenceLevel = dutTargetInputPower + dutExpectedGain + dutGainAccuracy + referenceWaveformPapr;
                switch vsaLoOffsetMode
                    case 'No Offset'
                        app.vsa.SetDownconverterFrequencyOffset('', 0);
                    case 'Automatic'
                        app.vsa.ResetAttribute('', RFmxInstrMXPropertyId.DownconverterFrequencyOffset);
                    case 'User Defined'
                        app.vsa.SetDownconverterFrequencyOffset('', vsaLoOffsetFrequency);
                end
                
                % Configure SpecAn Common %
                app.specAn.ConfigureRF('', centerFrequency, vsaReferenceLevel, vsaExternalAttenuation);
                app.specAn.ConfigureDigitalEdgeTrigger('', vsaTriggerSource, specAnTriggerEdge, vsaTriggerDelay, vsaTriggerState);
                
                % Configure DPD / SpecAn IQ Measurement %
                app.specAn.IQ.Configuration.ConfigureAcquisition('', referenceWaveformSampleRate, 1, dpdMeasurementInterval, 0);
                
                % Configure SpecAn DPD %
                app.specAn.Dpd.Configuration.ConfigureReferenceWaveform('', referenceNetWaveform, dpdReferenceWaveformIdleDurationPresent, dpdSignalType);
                app.specAn.Dpd.Configuration.ConfigureDutAverageInputPower('', dutTargetInputPower);
                app.specAn.Dpd.Configuration.ConfigureDpdModel('', dpdModel);
                app.specAn.Dpd.Configuration.ConfigureMemoryPolynomial('', dpdOrder, dpdRFmxMemory);
                app.specAn.Dpd.Configuration.ConfigureMeasurementSampleRate('', dpdSampleRateMode, referenceWaveformSampleRate);
                app.specAn.Dpd.Configuration.ConfigureMeasurementInterval('', dpdMeasurementInterval);
                if dpdInterations > 1
                    app.specAn.Dpd.Configuration.ConfigureIterativeDpdEnabled('', RFmxSpecAnMXDpdIterativeDpdEnabled.True);
                else
                    app.specAn.Dpd.Configuration.ConfigureIterativeDpdEnabled('', RFmxSpecAnMXDpdIterativeDpdEnabled.False);
                end
                
                % Configure SpecAn AMPM Measurement %
                app.specAn.Ampm.Configuration.ConfigureDutAverageInputPower('', dutTargetInputPower);
                app.specAn.Ampm.Configuration.ConfigureReferenceWaveform('', referenceNetWaveform, ampmreferenceWaveformIdleDurationPresent, ampmWaveformSignalType);
                app.specAn.Ampm.Configuration.ConfigureMeasurementInterval('', ampmMeasurementInterval);
                app.specAn.Ampm.Configuration.ConfigureThreshold('', ampmThresholdEnabled, ampmThresholdLevel, ampmThresholdType);
                app.specAn.Ampm.Configuration.ConfigureReferencePowerType('', ampmReferencePowerType);
                
                % Configure NR Common %
                app.nr.ConfigureRF('', centerFrequency, vsaReferenceLevel, vsaExternalAttenuation);
                app.nr.ConfigureDigitalEdgeTrigger('', vsaTriggerSource, nrTriggerEdge, vsaTriggerDelay, vsaTriggerState);
                app.nr.SelectMeasurements('', bitor(RFmxNRMXMeasurementTypes.Acp, RFmxNRMXMeasurementTypes.ModAcc), true);
                
                % Configure NR ModAcc Measurement %
                app.nr.ComponentCarrier.SetBandwidth('', referenceWaveformBandwidth);
                app.nr.ComponentCarrier.SetBandwidthPartSubcarrierSpacing ('', referenceWaveformSubcarrierSpacing);
                app.nr.ComponentCarrier.SetPuschTransformPrecodingEnabled  ('', referenceWaveformTransformPrecodingEnabled);
                app.nr.ComponentCarrier.SetPuschModulationType('', referenceWaveformModulationType);
                
                % Configure NR ACP Measurement %
                app.nr.Acp.Configuration.ConfigureNumberOfNROffsets('', acpNROffsets);
                app.nr.Acp.Configuration.ConfigureAveraging('', acpAveragingEnabled, acpAveragingNumber, acpAveragingType);
                app.nr.Acp.Configuration.ConfigureRbwFilter('', acpRbwAuto, acpRbwBandwidth, acpRbwFilterType);
                
                % Configure VSG %
                app.vsg.Arb.GenerationMode = RfsgWaveformGenerationMode.Script;
                app.vsg.RF.ExternalGain = -vsgExternalAttenuation;
                app.vsg.FrequencyReference.Configure(vsgFrequencyReferenceSource, 10e6);
                app.vsg.RF.Configure(centerFrequency, dutTargetInputPower);
                markerEvent1 = Item(app.vsg.DeviceEvents.MarkerEvents, 1);
                markerEvent1.ExportedOutputTerminal = vsgMarkerDestination;
                app.vsg.RF.Upconverter.FrequencyOffset = vsgLoOffsetFrequency;
                
                app.StatusTextLabel.Text = 'Downloading waveform..';
                drawnow limitrate % draw and interupt if needed
                
                % Download Reference Waveform to VSG %
                try
                    NIRfsgPlayback.ClearWaveform(app.vsgHandle, referenceWaveformName);
                catch
                end
                NIRfsgPlayback.ReadAndDownloadWaveformFromFile(app.vsgHandle, referenceWaveformPath, referenceWaveformName);
                NIRfsgPlayback.StoreWaveformLOOffsetMode(app.vsgHandle, referenceWaveformName, vsgLoOffsetMode);
                NIRfsgPlayback.SetScriptToGenerateSingleRfsg(app.vsgHandle, referenceWaveformScript);
                
                % Prep Display %
                axis(app.Graph0, "auto");
                axis(app.Graph1, "auto");
                axis(app.Graph2, "auto");
                app.UITable.Data = cell(10, 3);
                
                if dpdEnabled
                    app.StatusTextLabel.Text = 'Taking pre-DPD measurements..';
                    drawnow limitrate % draw and interupt if needed
                
                    % take pre-dpd measurements %
                    app.specAn.SelectMeasurements('', RFmxSpecAnMXMeasurementTypes.Ampm, true);
                    app.vsg.Initiate();
                    app.nr.Initiate('', 'preDpd');
                    app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                    app.specAn.Initiate('', 'preDpd');
                    app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                    app.vsg.Abort();
                    
                    % set predistorted waveform constants %
                    predistortedWaveformName = 'predistorted';
                    predistortedWaveformScript = 'script preScript repeat forever generate predistorted marker1(0) end repeat end script';
                    NIRfsgPlayback.StoreWaveformLOOffsetMode(app.vsgHandle, predistortedWaveformName, vsgLoOffsetMode);
                    
                    app.StatusTextLabel.Text = 'Configuring DPD toolchain..';
                    drawnow limitrate % draw and interupt if needed
                
                    switch dpdToolchain
                        case 'NanoSemi Linearizer'
                            % enable iq measurement %
                            app.specAn.SelectMeasurements('', RFmxSpecAnMXMeasurementTypes.IQ, true);
                            
                            % load nanosemi dpd dll %
                            if ~libisloaded('nstdpd')
                                loadlibrary('C:\NanoSemi\bin\nstdpd.dll', 'C:\NanoSemi\inc\nstdpd.h')
                            end
                            
                            % set dpd configuration %
                            [~, dpdConfig] = calllib('nstdpd', 'nst_dpd_get_default_config', []);
                            dpdConfig.lvl = dpdLevel;
                            dpdConfig.rho = dpdRho;
                            dpdConfig.training_samples = dpdTrainingSamples;
                            dpdConfig.abs_vsg_max = 1.01;
                            dpdConfig.f_sample = referenceWaveformSampleRate / 1e6;
                            calllib('nstdpd', 'nst_dpd_reset_training', dpdConfig);
                            
                        case 'NI RFmx'
                            % enable dpd measurement
                            app.specAn.SelectMeasurements('', RFmxSpecAnMXMeasurementTypes.Dpd, false);
                            
                        case 'MATLAB'
                            app.specAn.SelectMeasurements('', RFmxSpecAnMXMeasurementTypes.Ampm, true);
                            estimator = comm.DPDCoefficientEstimator;
                            estimator.DesiredAmplitudeGaindB = 0;
                            estimator.Degree = dpdOrder;
                            estimator.MemoryDepth = dpdMlMemory;
                            mldpd = comm.DPD;
                            
                        otherwise
                            error('Unknown DPD type')
                    end
                    
                    % start with the reference waveform %                    
                    pa_input = referenceWaveform;

                    % wait for ampm measurement to finish %
                    preDpdResultString = RFmxSpecAnMX.BuildResultString('preDpd');
                    app.specAn.WaitForMeasurementComplete(preDpdResultString, vsaMeasurementTimeout);
                    
                    % perform dpd training %
                    for i = 1:dpdInterations
                        app.StatusTextLabel.Text = ['Training DPD - Iteration', ' ', num2str(i)];
                        drawnow limitrate % draw and interupt if needed
                        switch dpdToolchain
                            case 'NanoSemi Linearizer'
                                % acquire data through pa %
                                app.vsg.Initiate();
                                app.specAn.Initiate('', '');
                                app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                                app.vsg.Abort();
                                [~, pa_output] = app.specAn.IQ.Results.FetchData('', vsaMeasurementTimeout, 0, -1, []);
                                pa_output = GetComplexArray(app, pa_output);
                                
                                % train nstdpd %
                                calllib('nstdpd', 'nst_dpd_train', real(pa_input), imag(pa_input), length(pa_output), real(pa_output), imag(pa_output), dpdConfig);
        
                                % predistort the reference waveform %
                                [~, ~, ~, predistI, predistQ] = ...
                                    calllib('nstdpd', 'nst_dpd_apply', real(referenceWaveform), imag(referenceWaveform), length(referenceWaveform), zeros(1, length(referenceWaveform)), zeros(1, length(referenceWaveform)), dpdConfig);
                                predistortedWaveform = predistI + 1j * predistQ;
                                predistortedNetWaveform = GetNetWaveform(app, predistortedWaveform);
                                predistortedNetWaveform.PrecisionTiming = referenceNetWaveform.PrecisionTiming;
                                
                                % Download Predistorted Waveform to VSG %
                                try
                                    NIRfsgPlayback.ClearWaveform(app.vsgHandle, predistortedWaveformName);
                                catch
                                end
                                NIRfsgPlayback.DownloadUserWaveform(app.vsgHandle, predistortedWaveformName, predistortedNetWaveform, referenceWaveformIdleDurationPresent); 
                                NIRfsgPlayback.StoreWaveformSampleRate(app.vsgHandle, predistortedWaveformName, referenceWaveformSampleRate);
                                
                            case 'NI RFmx'
                                % perform dpd measurement %
                                app.vsg.Initiate();
                                app.specAn.Initiate('', '');
                                app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                                app.vsg.Abort();
                                
                                % fetch results %
                                [~, predistortedWaveform, predistortedWaveformPapr, predistortedWaveformPowerOffset] = ...
                                    app.specAn.Dpd.ApplyDpd.ApplyDigitalPredistortion('', referenceNetWaveform, dpdApplyDpdIdleDurationPresent, vsaMeasurementTimeout, []);
                                
                                % Download Predistorted Waveform to VSG %
                                try
                                    NIRfsgPlayback.ClearWaveform(app.vsgHandle, predistortedWaveformName);
                                catch
                                end
                                NET.invokeGenericMethod(app.vsg.Arb, 'WriteWaveform', {'NationalInstruments.ComplexSingle'}, predistortedWaveformName, predistortedWaveform);
                                
                                % Apply Predistorted Waveform Properties %
                                NIRfsgPlayback.StoreWaveformSampleRate(app.vsgHandle, predistortedWaveformName, referenceWaveformSampleRate);
                                NIRfsgPlayback.StoreWaveformSignalBandwidth(app.vsgHandle, predistortedWaveformName, 0.8 * referenceWaveformSampleRate);
                                NIRfsgPlayback.StoreWaveformPapr(app.vsgHandle, predistortedWaveformName, predistortedWaveformPapr + predistortedWaveformPowerOffset);
                                NIRfsgPlayback.StoreWaveformRuntimeScaling(app.vsgHandle, predistortedWaveformName, -1.5);
                                
                            case 'MATLAB'
                                % acquire data through pa %
                                app.vsg.Initiate();
                                app.specAn.Initiate('', '');
                                app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                                app.vsg.Abort();
                                
                                % fetch time aligned pa input and pa output
                                % waveforms
                                [~, pa_output] = app.specAn.Ampm.Results.FetchProcessedMeanAcquiredWaveform('', vsaMeasurementTimeout, []);
                                [~, pa_input] = app.specAn.Ampm.Results.FetchProcessedReferenceWaveform('', vsaMeasurementTimeout, []);
                                
                                % convert to complex arrays %
                                pa_output = GetComplexArray(app, pa_output);
                                pa_input = GetComplexArray(app, pa_input);
                                
                                % scale pa input and output waveforms to have the same
                                % average power as the reference waveform
                                pa_output = ScaleComplexArray(app, pa_output, referenceWaveformAveragePower);
                                pa_input = ScaleComplexArray(app, pa_input, referenceWaveformAveragePower);
    
                                % predistort waveform %
                                mldpd.Coefficients = estimator(pa_input.', pa_output.');
                                predistortedWaveform = mldpd(referenceWaveform.');
                                
                                % Convert to .NET waveform %
                                predistortedNetWaveform = GetNetWaveform(app, predistortedWaveform);
                                predistortedNetWaveform.PrecisionTiming = referenceNetWaveform.PrecisionTiming;
                                
                                % Download Predistorted Waveform to VSG %
                                try
                                    NIRfsgPlayback.ClearWaveform(app.vsgHandle, predistortedWaveformName);
                                catch
                                end
                                NIRfsgPlayback.DownloadUserWaveform(app.vsgHandle, predistortedWaveformName, predistortedNetWaveform, referenceWaveformIdleDurationPresent); 
                                NIRfsgPlayback.StoreWaveformSampleRate(app.vsgHandle, predistortedWaveformName, referenceWaveformSampleRate);
                        end
                        
                        % set pa input to new predistorted waveform %
                        pa_input = predistortedWaveform;
                        
                        % configure instrument to play new predistorted
                        % waveform
                        NIRfsgPlayback.SetScriptToGenerateSingleRfsg(app.vsgHandle, predistortedWaveformScript);
                    end

                end
                
                % enable ampm measurement %
                app.specAn.SelectMeasurements('', RFmxSpecAnMXMeasurementTypes.Ampm, true);
                
                app.StatusTextLabel.Text = 'Taking post-DPD measurements..';
                drawnow limitrate % draw and interupt if needed
                
                % start the generator %
                app.vsg.Initiate();
                
                % loop and capture post dpd results %
                while run
                    hold(app.Graph0, "off");
                    hold(app.Graph1, "off");
                    hold(app.Graph2, "off");
                    if dpdEnabled
                        app.nr.Initiate('', 'postDpd');
                        app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                        app.specAn.Initiate('', 'postDpd');
                        app.nr.WaitForMeasurementComplete('result::postDpd', vsaMeasurementTimeout);
                        app.specAn.WaitForMeasurementComplete('result::postDpd', vsaMeasurementTimeout);
                        app.UpdateDisplay('preDpd', vsaMeasurementTimeout);
                        hold(app.Graph0, "on");
                        hold(app.Graph1, "on");
                        hold(app.Graph2, "on");
                        app.UpdateDisplay('postDpd', vsaMeasurementTimeout);
                    else
                        app.nr.Initiate('', 'preDpd');
                        app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                        app.specAn.Initiate('', 'preDpd');
                        app.vsa.WaitForAcquisitionComplete(vsaTriggerTimeout);
                        app.UpdateDisplay('preDpd', vsaMeasurementTimeout);
                    end
                    drawnow limitrate % draw and interupt if needed
                    
                    run = app.RunButton.Value;
                end
                
                app.vsg.Abort();
                
            end
            
            app.StatusTextLabel.Text = 'Waiting on user..';
            drawnow limitrate % draw and interupt if needed
            
        end

        % Value changed function: VsaLoOffsetModeDropDown
        function VsaLoOffsetModeDropDownValueChanged(app, event)
            app.VsaLoOffsetFrequencyEditField.Enable = strcmp(app.VsaLoOffsetModeDropDown.Value, 'User Defined');
        end

        % Value changed function: AcpAveragingEnabledCheckBox
        function AcpAveragingEnabledCheckBoxValueChanged(app, event)
            value = app.AcpAveragingEnabledCheckBox.Value;
            app.AcpAveragingNumberEditField.Enable = value;
            app.AcpAveragingTypeDropDown.Enable = value;
        end

        % Value changed function: AcpRbwAutoCheckBox
        function AcpRbwAutoCheckBoxValueChanged(app, event)
            app.AcpRbwBandwidthEditField.Enable = ~app.AcpRbwAutoCheckBox.Value;
        end

        % Value changed function: Graph2DropDown
        function Graph2DropDownValueChanged(app, event)
            axis(app.Graph2, "auto");
        end

        % Value changed function: Graph1DropDown
        function Graph1DropDownValueChanged(app, event)
            axis(app.Graph1, "auto");
        end

        % Value changed function: VsgLoOffsetModeDropDown
        function VsgLoOffsetModeDropDownValueChanged(app, event)
            app.VsgLoOffsetFrequencyEditField.Enable = strcmp(app.VsgLoOffsetModeDropDown.Value, 'User Defined');
        end

        % Value changed function: AmpmThresholdState
        function AmpmThresholdStateValueChanged(app, event)
            app.AmpmThresholdLevel.Enable = strcmp(app.AmpmThresholdState.Value, 'Enabled');
        end

        % Value changed function: VsaTriggerStateDropDown
        function VsaTriggerStateDropDownValueChanged(app, event)
            value = strcmp(app.VsaTriggerStateDropDown.Value, 'Enabled');
            app.VsaTriggerSourceDropDown.Enable = value;
            app.VsaTriggerEdgeDropDown.Enable = value;
            app.VsaTriggerDelaySpinner.Enable = value;
            app.VsaTriggerTimeoutSpinner.Enable = value;
        end

        % Value changed function: DpdToolchainDropDown
        function DpdToolchainDropDownValueChanged(app, event)
            nstdpdEnabled = strcmp(app.DpdToolchainDropDown.Value, 'NanoSemi Linearizer');
            app.DpdLevelDropDown.Enable = nstdpdEnabled;
            app.DpdRhoEditField.Enable = nstdpdEnabled;
            app.DpdTrainingSamplesEditField.Enable = nstdpdEnabled;
            app.DpdOrderSpinner.Enable = ~nstdpdEnabled;
            app.DpdMemorySpinner.Enable = ~nstdpdEnabled;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.tdms');
            app.WaveformPathEditField.Value = fullfile(path, file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NationalInstrumentsMATLABDPDDemoUIFigure = uifigure('Visible', 'off');
            app.NationalInstrumentsMATLABDPDDemoUIFigure.Name = 'National Instruments MATLAB DPD Demo';
            app.NationalInstrumentsMATLABDPDDemoUIFigure.CloseRequestFcn = createCallbackFcn(app, @NationalInstrumentsMATLABDPDDemoUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            app.DUTWaveformTab = uitab(app.TabGroup);
            app.DUTWaveformTab.Title = 'DUT & Waveform';
            app.TabGroup2 = uitabgroup(app.DUTWaveformTab);
            app.DUTTab = uitab(app.TabGroup2);
            app.DUTTab.Title = 'DUT';
            app.CenterFrequencyHzLabel = uilabel(app.DUTTab);
            app.CenterFrequencyHzLabel.Text = 'Center Frequency (Hz)';
            app.CenterFrequencyEditField = uieditfield(app.DUTTab, 'numeric');
            app.CenterFrequencyEditField.Limits = [0 Inf];
            app.CenterFrequencyEditField.Value = 2150000000;
            app.TargetInputPowerdBmLabel = uilabel(app.DUTTab);
            app.TargetInputPowerdBmLabel.Text = 'Target Input Power (dBm)';
            app.TargetInputPowerEditField = uieditfield(app.DUTTab, 'numeric');
            app.TargetInputPowerEditField.Value = -18;
            app.ExpectedGaindBEditFieldLabel = uilabel(app.DUTTab);
            app.ExpectedGaindBEditFieldLabel.Text = 'Expected Gain (dB)';
            app.ExpectedGainEditField = uieditfield(app.DUTTab, 'numeric');
            app.ExpectedGainEditField.Value = 50;
            app.GainAccuracydBEditFieldLabel = uilabel(app.DUTTab);
            app.GainAccuracydBEditFieldLabel.Text = 'Gain Accuracy (dB)';
            app.GainAccuracyEditField = uieditfield(app.DUTTab, 'numeric');
            app.GainAccuracyEditField.Value = 1;
            app.WaveformTab_2 = uitab(app.TabGroup2);
            app.WaveformTab_2.Title = 'Waveform';
            app.PathLabel = uilabel(app.WaveformTab_2);
            app.PathLabel.Text = 'Path';
            app.WaveformPathEditField = uieditfield(app.WaveformTab_2, 'text');
            app.WaveformPathEditField.Value = 'Waveforms\NI_NR100_QPSK_OS4.tdms';
            app.BurstedCheckBox = uicheckbox(app.WaveformTab_2);
            app.BurstedCheckBox.Text = 'Bursted';
            app.SignalTypeDropDownLabel = uilabel(app.WaveformTab_2);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.WaveformSignalTypeDropDown = uidropdown(app.WaveformTab_2);
            app.WaveformSignalTypeDropDown.Items = {'Tones', 'Modulated'};
            app.WaveformSignalTypeDropDown.Value = 'Modulated';
            app.BrowseButton = uibutton(app.WaveformTab_2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.GNRTab = uitab(app.TabGroup2);
            app.GNRTab.Title = '5GNR';
            app.BandwidthMHzDropDownLabel = uilabel(app.GNRTab);
            app.BandwidthMHzDropDownLabel.Text = 'Bandwidth (MHz)';
            app.BandwidthDropDown = uidropdown(app.GNRTab);
            app.BandwidthDropDown.Items = {'5', '10', '15', '20', '25', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.BandwidthDropDown.Value = '100';
            app.TransformPrecodingCheckBox = uicheckbox(app.GNRTab);
            app.TransformPrecodingCheckBox.Text = 'Transform Precoding (DTF-s-OFDM)';
            app.ModulationTypeDropDownLabel = uilabel(app.GNRTab);
            app.ModulationTypeDropDownLabel.Text = 'Modulation Type';
            app.ModulationTypeDropDown = uidropdown(app.GNRTab);
            app.ModulationTypeDropDown.Items = {'PI/2 BPSK', 'QPSK', 'QAM16', 'QAM64', 'QAM256'};
            app.ModulationTypeDropDown.Value = 'QPSK';
            app.SubcarrierSpacingkHzDropDownLabel = uilabel(app.GNRTab);
            app.SubcarrierSpacingkHzDropDownLabel.Text = 'Subcarrier Spacing (kHz)';
            app.SubcarrierSpacingDropDown = uidropdown(app.GNRTab);
            app.SubcarrierSpacingDropDown.Items = {'15', '30', '60'};
            app.SubcarrierSpacingDropDown.Value = '30';
            app.MeasurementsTab = uitab(app.TabGroup);
            app.MeasurementsTab.Title = 'Measurements';
            app.TabGroup4 = uitabgroup(app.MeasurementsTab);
            app.DPDTab = uitab(app.TabGroup4);
            app.DPDTab.Title = 'DPD';
            app.DpdEnabledCheckBox = uicheckbox(app.DPDTab);
            app.DpdEnabledCheckBox.Text = 'Enabled';
            app.DpdEnabledCheckBox.Value = true;
            app.IterationsSpinnerLabel = uilabel(app.DPDTab);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.DpdIterationsSpinner = uispinner(app.DPDTab);
            app.DpdIterationsSpinner.Value = 3;
            app.MeasurementIntervalsEditFieldLabel_2 = uilabel(app.DPDTab);
            app.MeasurementIntervalsEditFieldLabel_2.Text = 'Measurement Interval (s)';
            app.DpdMeasurementIntervalEditField = uieditfield(app.DPDTab, 'numeric');
            app.DpdMeasurementIntervalEditField.Value = 0.0002;
            app.NanoSemiLinearizerPanel = uipanel(app.DPDTab);
            app.NanoSemiLinearizerPanel.Title = 'NanoSemi Linearizer';
            app.LevelDropDownLabel = uilabel(app.NanoSemiLinearizerPanel);
            app.LevelDropDownLabel.Text = 'Level';
            app.DpdLevelDropDown = uidropdown(app.NanoSemiLinearizerPanel);
            app.DpdLevelDropDown.Items = {'Level 0', 'Level 1', 'Level 2', 'Level 3'};
            app.DpdLevelDropDown.Value = 'Level 2';
            app.TrainingSamplesEditFieldLabel = uilabel(app.NanoSemiLinearizerPanel);
            app.TrainingSamplesEditFieldLabel.Text = 'Training Samples';
            app.DpdTrainingSamplesEditField = uieditfield(app.NanoSemiLinearizerPanel, 'numeric');
            app.DpdTrainingSamplesEditField.Value = 25000;
            app.RhoEditFieldLabel = uilabel(app.NanoSemiLinearizerPanel);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.DpdRhoEditField = uieditfield(app.NanoSemiLinearizerPanel, 'numeric');
            app.DpdRhoEditField.Value = 0.1;
            app.ToolchainDropDownLabel = uilabel(app.DPDTab);
            app.ToolchainDropDownLabel.Text = 'Toolchain';
            app.DpdToolchainDropDown = uidropdown(app.DPDTab);
            app.DpdToolchainDropDown.Items = {'NanoSemi Linearizer', 'NI RFmx', 'MATLAB'};
            app.DpdToolchainDropDown.ValueChangedFcn = createCallbackFcn(app, @DpdToolchainDropDownValueChanged, true);
            app.DpdToolchainDropDown.Value = 'NanoSemi Linearizer';
            app.MemoryPolynomialPanel = uipanel(app.DPDTab);
            app.MemoryPolynomialPanel.Title = 'Memory Polynomial';
            app.OrderSpinnerLabel = uilabel(app.MemoryPolynomialPanel);
            app.OrderSpinnerLabel.Enable = 'off';
            app.OrderSpinnerLabel.Text = 'Order';
            app.DpdOrderSpinner = uispinner(app.MemoryPolynomialPanel);
            app.DpdOrderSpinner.Enable = 'off';
            app.DpdOrderSpinner.Value = 7;
            app.MemorySpinnerLabel = uilabel(app.MemoryPolynomialPanel);
            app.MemorySpinnerLabel.Enable = 'off';
            app.MemorySpinnerLabel.Text = 'Memory';
            app.DpdMemorySpinner = uispinner(app.MemoryPolynomialPanel);
            app.DpdMemorySpinner.Enable = 'off';
            app.DpdMemorySpinner.Value = 2;
            app.AMPMTab = uitab(app.TabGroup4);
            app.AMPMTab.Title = 'AMPM';
            app.MeasurementIntervalsEditFieldLabel = uilabel(app.AMPMTab);
            app.MeasurementIntervalsEditFieldLabel.Text = 'Measurement Interval (s)';
            app.AmpmMeasurementIntervalEditField = uieditfield(app.AMPMTab, 'numeric');
            app.AmpmMeasurementIntervalEditField.Value = 0.0002;
            app.ThresholdPanel = uipanel(app.AMPMTab);
            app.ThresholdPanel.Title = 'Threshold';
            app.StateDropDownLabel_2 = uilabel(app.ThresholdPanel);
            app.StateDropDownLabel_2.Text = 'State';
            app.AmpmThresholdState = uidropdown(app.ThresholdPanel);
            app.AmpmThresholdState.Items = {'Disabled', 'Enabled'};
            app.AmpmThresholdState.ValueChangedFcn = createCallbackFcn(app, @AmpmThresholdStateValueChanged, true);
            app.AmpmThresholdState.Value = 'Enabled';
            app.LeveldBPeakEditFieldLabel = uilabel(app.ThresholdPanel);
            app.LeveldBPeakEditFieldLabel.Text = 'Level (dB Peak)';
            app.AmpmThresholdLevel = uieditfield(app.ThresholdPanel, 'numeric');
            app.AmpmThresholdLevel.Value = -20;
            app.ACPTab = uitab(app.TabGroup4);
            app.ACPTab.Title = 'ACP';
            app.AveragingPanel = uipanel(app.ACPTab);
            app.AveragingPanel.Title = 'Averaging';
            app.NumberLabel = uilabel(app.AveragingPanel);
            app.NumberLabel.Enable = 'off';
            app.NumberLabel.Text = 'Number';
            app.AcpAveragingNumberEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.AcpAveragingNumberEditField.Enable = 'off';
            app.AcpAveragingNumberEditField.Value = 10;
            app.AcpAveragingEnabledCheckBox = uicheckbox(app.AveragingPanel);
            app.AcpAveragingEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcpAveragingEnabledCheckBoxValueChanged, true);
            app.AcpAveragingEnabledCheckBox.Text = 'Enabled';
            app.TypeDropDownLabel = uilabel(app.AveragingPanel);
            app.TypeDropDownLabel.Enable = 'off';
            app.TypeDropDownLabel.Text = 'Type';
            app.AcpAveragingTypeDropDown = uidropdown(app.AveragingPanel);
            app.AcpAveragingTypeDropDown.Items = {'RMS'};
            app.AcpAveragingTypeDropDown.Enable = 'off';
            app.AcpAveragingTypeDropDown.Value = 'RMS';
            app.RBWFilterPanel = uipanel(app.ACPTab);
            app.RBWFilterPanel.Title = 'RBW Filter';
            app.AcpRbwAutoCheckBox = uicheckbox(app.RBWFilterPanel);
            app.AcpRbwAutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcpRbwAutoCheckBoxValueChanged, true);
            app.AcpRbwAutoCheckBox.Text = 'Auto Bandwidth';
            app.AcpRbwAutoCheckBox.Value = true;
            app.BandwidthHzEditFieldLabel = uilabel(app.RBWFilterPanel);
            app.BandwidthHzEditFieldLabel.Enable = 'off';
            app.BandwidthHzEditFieldLabel.Text = 'Bandwidth (Hz)';
            app.AcpRbwBandwidthEditField = uieditfield(app.RBWFilterPanel, 'numeric');
            app.AcpRbwBandwidthEditField.Enable = 'off';
            app.AcpRbwBandwidthEditField.Value = 1000000;
            app.FilterTypeLabel = uilabel(app.RBWFilterPanel);
            app.FilterTypeLabel.Text = 'Filter Type';
            app.AcpRbwFilterTypeDropDown = uidropdown(app.RBWFilterPanel);
            app.AcpRbwFilterTypeDropDown.Items = {'Gaussian'};
            app.AcpRbwFilterTypeDropDown.Value = 'Gaussian';
            app.NROffsetsSpinnerLabel = uilabel(app.ACPTab);
            app.NROffsetsSpinnerLabel.Enable = 'off';
            app.NROffsetsSpinnerLabel.Text = 'NR Offsets';
            app.AcpNROffsetsSpinner = uispinner(app.ACPTab);
            app.AcpNROffsetsSpinner.Enable = 'off';
            app.AcpNROffsetsSpinner.Value = 2;
            app.HardwareTab = uitab(app.TabGroup);
            app.HardwareTab.Title = 'Hardware';
            app.TabGroup3 = uitabgroup(app.HardwareTab);
            app.VSATab = uitab(app.TabGroup3);
            app.VSATab.Title = 'VSA';
            app.TriggerPanel = uipanel(app.VSATab);
            app.TriggerPanel.Title = 'Trigger';
            app.SourceDropDownLabel = uilabel(app.TriggerPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.VsaTriggerSourceDropDown = uidropdown(app.TriggerPanel);
            app.VsaTriggerSourceDropDown.Items = {'PXI_Trig0', 'PXI_Trig1', 'PXI_Trig2', 'PXI_Trig3', 'PXI_Trig4', 'PXI_Trig5', 'PXI_Trig6', 'PXI_Trig7'};
            app.VsaTriggerSourceDropDown.Value = 'PXI_Trig0';
            app.EdgeDropDownLabel = uilabel(app.TriggerPanel);
            app.EdgeDropDownLabel.Text = 'Edge';
            app.VsaTriggerEdgeDropDown = uidropdown(app.TriggerPanel);
            app.VsaTriggerEdgeDropDown.Items = {'Rising', 'Falling'};
            app.VsaTriggerEdgeDropDown.Value = 'Rising';
            app.DelaysSpinnerLabel = uilabel(app.TriggerPanel);
            app.DelaysSpinnerLabel.Text = 'Delay (s)';
            app.VsaTriggerDelaySpinner = uispinner(app.TriggerPanel);
            app.TimeoutsSpinnerLabel = uilabel(app.TriggerPanel);
            app.TimeoutsSpinnerLabel.Text = 'Timeout (s)';
            app.VsaTriggerTimeoutSpinner = uispinner(app.TriggerPanel);
            app.VsaTriggerTimeoutSpinner.Value = 10;
            app.StateDropDownLabel = uilabel(app.TriggerPanel);
            app.StateDropDownLabel.Text = 'State';
            app.VsaTriggerStateDropDown = uidropdown(app.TriggerPanel);
            app.VsaTriggerStateDropDown.Items = {'Disabled', 'Enabled'};
            app.VsaTriggerStateDropDown.ValueChangedFcn = createCallbackFcn(app, @VsaTriggerStateDropDownValueChanged, true);
            app.VsaTriggerStateDropDown.Value = 'Enabled';
            app.LOOffsetPanel = uipanel(app.VSATab);
            app.LOOffsetPanel.Title = 'LO Offset';
            app.ModeDropDownLabel = uilabel(app.LOOffsetPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.VsaLoOffsetModeDropDown = uidropdown(app.LOOffsetPanel);
            app.VsaLoOffsetModeDropDown.Items = {'No Offset', 'Automatic', 'User Defined'};
            app.VsaLoOffsetModeDropDown.ValueChangedFcn = createCallbackFcn(app, @VsaLoOffsetModeDropDownValueChanged, true);
            app.VsaLoOffsetModeDropDown.Value = 'Automatic';
            app.OffsetHzEditFieldLabel = uilabel(app.LOOffsetPanel);
            app.OffsetHzEditFieldLabel.Enable = 'off';
            app.OffsetHzEditFieldLabel.Text = 'Offset (Hz)';
            app.VsaLoOffsetFrequencyEditField = uieditfield(app.LOOffsetPanel, 'numeric');
            app.VsaLoOffsetFrequencyEditField.Enable = 'off';
            app.ResourceNameLabel_2 = uilabel(app.VSATab);
            app.ResourceNameLabel_2.Text = {'Resource Name'; ''};
            app.VsaResourceNameDropDown = uidropdown(app.VSATab);
            app.VsaResourceNameDropDown.Items = {'<No Device>'};
            app.VsaResourceNameDropDown.ValueChangedFcn = createCallbackFcn(app, @VsaResourceChanged, true);
            app.VsaResourceNameDropDown.Value = '<No Device>';
            app.ExternalAttenuationdBEditFieldLabel_2 = uilabel(app.VSATab);
            app.ExternalAttenuationdBEditFieldLabel_2.Text = 'External Attenuation (dB)';
            app.VsaExternalAttenuationEditField = uieditfield(app.VSATab, 'numeric');
            app.VsaExternalAttenuationEditField.Value = 42;
            app.FrequencyReferenceDropDown_2Label = uilabel(app.VSATab);
            app.FrequencyReferenceDropDown_2Label.Text = 'Frequency Reference';
            app.VsaFrequencyReferenceDropDown = uidropdown(app.VSATab);
            app.VsaFrequencyReferenceDropDown.Items = {'OnboardClock', 'RefIn', 'PXI_CLK'};
            app.VsaFrequencyReferenceDropDown.Value = 'OnboardClock';
            app.MeasurementTimeoutsEditFieldLabel = uilabel(app.VSATab);
            app.MeasurementTimeoutsEditFieldLabel.Text = 'Measurement Timeout (s)';
            app.VsaMeasurementTimeoutEditField = uieditfield(app.VSATab, 'numeric');
            app.VsaMeasurementTimeoutEditField.Value = 10;
            app.VSGTab = uitab(app.TabGroup3);
            app.VSGTab.Title = 'VSG';
            app.MarkerPanel = uipanel(app.VSGTab);
            app.MarkerPanel.Title = 'Marker';
            app.DestinationDropDownLabel = uilabel(app.MarkerPanel);
            app.DestinationDropDownLabel.Text = 'Destination';
            app.VsgMarkerDestinationDropDown = uidropdown(app.MarkerPanel);
            app.VsgMarkerDestinationDropDown.Items = {'PXI_Trig0', 'PXI_Trig1', 'PXI_Trig2', 'PXI_Trig3', 'PXI_Trig4', 'PXI_Trig5', 'PXI_Trig6', 'PXI_Trig7'};
            app.VsgMarkerDestinationDropDown.Value = 'PXI_Trig0';
            app.LOOffsetPanel_2 = uipanel(app.VSGTab);
            app.LOOffsetPanel_2.Title = 'LO Offset';
            app.ModeDropDownLabel_2 = uilabel(app.LOOffsetPanel_2);
            app.ModeDropDownLabel_2.Text = 'Mode';
            app.VsgLoOffsetModeDropDown = uidropdown(app.LOOffsetPanel_2);
            app.VsgLoOffsetModeDropDown.Items = {'No Offset', 'Automatic', 'User Defined'};
            app.VsgLoOffsetModeDropDown.ValueChangedFcn = createCallbackFcn(app, @VsgLoOffsetModeDropDownValueChanged, true);
            app.VsgLoOffsetModeDropDown.Value = 'No Offset';
            app.OffsetHzEditFieldLabel_2 = uilabel(app.LOOffsetPanel_2);
            app.OffsetHzEditFieldLabel_2.Enable = 'off';
            app.OffsetHzEditFieldLabel_2.Text = 'Offset (Hz)';
            app.VsgLoOffsetFrequencyEditField = uieditfield(app.LOOffsetPanel_2, 'numeric');
            app.VsgLoOffsetFrequencyEditField.Enable = 'off';
            app.ResourceNameLabel = uilabel(app.VSGTab);
            app.ResourceNameLabel.Text = {'Resource Name'; ''};
            app.VsgResourceNameDropDown = uidropdown(app.VSGTab);
            app.VsgResourceNameDropDown.Items = {'<No Device>'};
            app.VsgResourceNameDropDown.ValueChangedFcn = createCallbackFcn(app, @VsgResourceChanged, true);
            app.VsgResourceNameDropDown.Value = '<No Device>';
            app.ExternalAttenuationdBEditFieldLabel = uilabel(app.VSGTab);
            app.ExternalAttenuationdBEditFieldLabel.Text = 'External Attenuation (dB)';
            app.VsgExternalAttenuationEditField = uieditfield(app.VSGTab, 'numeric');
            app.VsgExternalAttenuationEditField.Value = 0.5;
            app.FrequencyReferenceDropDownLabel = uilabel(app.VSGTab);
            app.FrequencyReferenceDropDownLabel.Text = 'Frequency Reference';
            app.VsgFrequencyReferenceDropDown = uidropdown(app.VSGTab);
            app.VsgFrequencyReferenceDropDown.Items = {'OnboardClock', 'RefIn', 'PXI_CLK'};
            app.VsgFrequencyReferenceDropDown.Value = 'OnboardClock';
            app.RunButton = uibutton(app.NationalInstrumentsMATLABDPDDemoUIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.Graph0 = uiaxes(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            title(app.Graph0, '')
            xlabel(app.Graph0, 'Frequency (Hz)')
            ylabel(app.Graph0, 'Power (dBm)')
            app.Graph1 = uiaxes(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            title(app.Graph1, '')
            xlabel(app.Graph1, '')
            ylabel(app.Graph1, '')
            app.Graph0DropDown = uidropdown(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            app.Graph0DropDown.Items = {'ACP'};
            app.Graph0DropDown.Value = 'ACP';
            app.Graph1DropDown = uidropdown(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            app.Graph1DropDown.Items = {'Constellation', 'EVM vs. Subcarrier', 'EVM vs. Symbol'};
            app.Graph1DropDown.ValueChangedFcn = createCallbackFcn(app, @Graph1DropDownValueChanged, true);
            app.Graph1DropDown.Value = 'Constellation';
            app.Graph2 = uiaxes(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            title(app.Graph2, '')
            xlabel(app.Graph2, 'Input Power (dBm)')
            ylabel(app.Graph2, 'Gain (dB)')
            app.Graph2DropDown = uidropdown(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            app.Graph2DropDown.Items = {'AM-Gain', 'AM-AM', 'AM-PM'};
            app.Graph2DropDown.ValueChangedFcn = createCallbackFcn(app, @Graph2DropDownValueChanged, true);
            app.Graph2DropDown.Value = 'AM-Gain';
            app.UITable = uitable(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            app.UITable.ColumnName = {'Measurement'; 'Before DPD'; 'After DPD'};
            app.UITable.RowName = {};
            app.StatusTextLabel = uilabel(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            app.StatusTextLabel.Text = 'Waiting on user..';
            app.StatusLabel = uilabel(app.NationalInstrumentsMATLABDPDDemoUIFigure);
            app.StatusLabel.Text = 'Status:';
            % Show the figure after all components are created
            app.NationalInstrumentsMATLABDPDDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dpd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NationalInstrumentsMATLABDPDDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NationalInstrumentsMATLABDPDDemoUIFigure)
        end
    end
end